classdef NewtonRaphsonApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditField2       matlab.ui.control.EditField
        EditField2Label  matlab.ui.control.Label
        TextArea         matlab.ui.control.TextArea
        TextAreaLabel    matlab.ui.control.Label
        Button           matlab.ui.control.Button
        EditField        matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        Label            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
       f_str = app.EditField2.Value; % Make sure this matches your function input field name
        if isa(f_str, 'char') || isa(f_str, 'string')
             if ~startsWith(f_str, '@')
                  f_str = ['@(x) ', f_str];
           end
            f = str2func(f_str);
        else
            f = f_str;
        end
        x = app.EditField.Value; % Make sure this matches your initial guess field name
        
        % Algorithm parameters
        tol = 1e-6;
        max_iter = 100;
        h = 1e-5; % step size for numerical derivative
        
        for i = 1:max_iter
            % Approximate derivative: f'(x) = [f(x+h) - f(x-h)] / (2*h)
            dfx = (f(x + h) - f(x - h)) / (2 * h);
            
            if abs(dfx) < 1e-12
                app.TextArea.Value = {'Derivative too small. Method failed.'};
                return;
            end
            
            % Update root estimate
            x_new = x - f(x) / dfx;
            
            % Check for convergence
            if abs(x_new - x) < tol
                app.TextArea.Value = { ...
                    sprintf('Success! Root found at x = %.6f', x_new), ...
                    sprintf('Number of iterations: %d', i), ...
                    sprintf('Function value f(x) = %.2e', f(x_new)) ...
                };
                return;
            end
            x = x_new;
        end
        
        app.TextArea.Value = {'Maximum iterations exceeded without convergence.'};
        
     ME
        app.TextArea.Value = {['Error: ', ME.message]};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewtonRaphsonApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end